classdef DLSaveraging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Filename                       matlab.ui.control.EditField
        FilenameofdisplayeddataEditFieldLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        TraceCountEditField            matlab.ui.control.NumericEditField
        TraceCountEditFieldLabel       matlab.ui.control.Label
        NoButton                       matlab.ui.control.Button
        YesButton                      matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ScatteringangleEditField       matlab.ui.control.NumericEditField
        ScatteringangleEditFieldLabel  matlab.ui.control.Label
        SelectfilesButton              matlab.ui.control.Button
        CHB                            matlab.ui.control.UIAxes
        CHA                            matlab.ui.control.UIAxes
        Corr                           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
%         sum % Cell array for all summed values of g2 and channel columns
%         data % Cell array holding all data columns of interest
        lag % Property to hold first column of second data block ((lagTime)
        count % Property to hold first column of last data block (countRate)
        lagTime % Variable to just use outside of main function scope
        countRate  % Variable to just use outside of main function scope
        index % Index holding the number of selected files
        sz % Size of selected traces
        yesFiles % Array to store which indices we are averaging
        saveName % Handle to hold the file name of the last accepted trace
        g2min % Variable to hold last minimum index of useful trace for g2 data
        min % Variable to hold  last minimum index of useful trace for last data block
        new % Cell array to hold each .dat file data for every run through
        newMin % Cell array to hold minimum g2 table dimensions to properly apply stitched avg.
        Indices % Global property to hold indices at the split of each block, in each file
    end
    
    methods (Access = private)
        
        function UserInput(app)
            app.Panel.Visible = 1;
            uiwait(app.UIFigure)
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            app.TraceCountEditField.Value = 0;
            
            [file,path] = uigetfile('*.dat','Select one or more input files','MultiSelect','on');
            %Format to keep all decimal places
            format short

            % Set large possible index of end of file
            app.min = 1000000;
            app.g2min = 1000000;

            app.sz = size(file,2);
            app.yesFiles = nan(1,app.sz);

            % Preallocate cell array for sum
            sum = cell(3,1);
            sum(:,1) = {0};

            if isa(file,"cell")
                for i = 1:1:size(file,2)
                    app.index = i;
                    % Open file and read all lines to create string w/ same format
                    default = readlines([path, file{1,i}]);
                    app.Filename.Value = file{1,i};

                    % Extract scattering angle for display
                    AngleTxt = regexp(default,'Scattering angle:	');     % Get cell array to see where Scattering angle is.
                    AngleInd = cellfun(@isempty,AngleTxt)==0;               % Make logical array to extract line index.
                    Angle = str2double(extractAfter(default(AngleInd),'Scattering angle:	'));
                    app.ScatteringangleEditField.Value = Angle;             % Display angle.

                    % Split at the line-breaks to find separated data blocks
                    % Create new cell matrix of strings, broken at each blank line
                    app.new = regexp(default,'\s\n\s','split');

                    % Find which lines are empty to get indices between blocks per file
                    logic = cellfun(@isempty,default);
                    indices = find(logic);
                    app.Indices = indices;
                    
%                     % Preallocate data
%                     data{1,size(file,2)} = cell(indices(1)+2:indices(2)-1,1);
%                     data{2,size(file,2)} = cell(indices(2)+2:indices(3)-1,1);

                    % Separate each block into its own cell array
                    data{1,i} = app.new(indices(1)+2:indices(2)-1,1);   % g2-1 data column
                    data{2,i} = app.new(indices(2)+2:indices(3)-1,1);   % Channel columns
                    
                    %%Separate every element of each column into doubles to average later
                    %%Keep first column of both blocks untouched
                    
                    % Have both split blocks in new cell arrays containing
                    % the split cell arrays
                    for q = 1:2
                        splitCells{1,q} = cellfun(@split, data{q,i}, 'UniformOutput', false);
                    end

                    % Reset array varriables to ensure cut off of hanging values
                    app.lagTime = "";
                    g2 = zeros(size(data{1,i}));
                    app.countRate = "";
                    y2 = zeros(size(data{2,i}));
                    z2 = zeros(size(data{2,i}));
                    
                    % Turn into doubles respective to each block's size
                    for indx = 1:1:size(splitCells{1,1})
                        app.lagTime(indx,1) = splitCells{1,1}{indx,1}(1);
                        g2(indx,1) = str2double(splitCells{1,1}{indx,1}(2));
                    end
                    
                    for indx = 1:1:size(splitCells{1,2})
                        app.countRate(indx,1) = splitCells{1,2}{indx,1}(1);
                        y2(indx,1) = str2double(splitCells{1,2}{indx,1}(2));
                        z2(indx,1) = str2double(splitCells{1,2}{indx,1}(3));
                    end

                    % Select the values we are averaging for 1st block
                    data{1,i} = g2(:);
                    
                    % Select the values we are averaging for 2nd block
                    data{2,i} = y2(:);
                    data{3,i} = z2(:);
                    
                    % Correlation plot
                    xvaluesPlot = str2double(app.lagTime);
                    plot(app.Corr,xvaluesPlot,data{1,i});
                    ref = refline(app.Corr,0, 0);
                    ref.Color = [0 0 0];
                    
                    % Separate channel plots
                    x2plot = str2double(app.countRate);
                    plot(app.CHA,x2plot,y2);
                    plot(app.CHB,x2plot,z2);
                    UserInput(app);
                end %End loop cycling through files
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % Remove nan files from unused trace indices. 
                % Then find array index equivalent from imported data indices(1:3) variable
                app.yesFiles = rmmissing(app.yesFiles);
                second = (app.g2min-1) - (indices(1));
                cap = (app.min-1) - (app.g2min);
                
                for i = 1:size(app.yesFiles,2)       
                    % Delete useless data points
                    data{1,app.yesFiles(i)}(second:end) = [];
                    data{2,app.yesFiles(i)}(cap:end) = [];
                    data{3,app.yesFiles(i)}(cap:end) = [];

                    % Summate each block over all data sets
                    sum{1,1} = sum{1,1} + data{1,app.yesFiles(i)};
                    sum{2,1} = sum{2,1} + data{2,app.yesFiles(i)};
                    sum{3,1} = sum{3,1} + data{3,app.yesFiles(i)};
                end

                % Make sure more than one trace is being used
                if isempty(sum)
                    uialert(app.UIFigure,"You must select at least one file to average","Select more than one");
                    return;
                end
                    
                % Now that we've looked at every file, calculate average of
                % each column. Create new cell array to hold avgs. Set w/ 0
                avgY = cell(3,1);
                avgY(:,1) = {0};

                for rows = 1:3
                    avgY{rows,1} = sum{rows,1}./app.sz;
                    avgY{rows,1} = num2str(avgY{rows,1}, '%.6f');
                    avgY{rows,1} = string(avgY{rows,1});
                    avgY{rows,1} = strtrim(avgY{rows,1});
                end

                % Don't forget to remove cutoff on the unaveraged x columns as well
                app.lag(second:end) = [];
                app.count(cap:end) = [];

                % Format each block uniquely back into strings
                for ind=1:size(avgY{1,1},1)
                    import(ind,:) = compose("%s\t%s",app.lag(ind,:), avgY{1,1}(ind,:));
                end

                tst = size(avgY{2,1},1);
                tst1 = app.count;
                tst2 = avgY{2,1};
                tst3 = avgY{3,1};

                for ind=1:size(avgY{2,1},1)
                    import2(ind,:) = compose("%s\t%s\t%s",app.count(ind,:),avgY{2,1}(ind,:),avgY{3,1}(ind,:));
                end

                % Need to get cell tables in proper format for merge
                import = convertStringsToChars(import);
                import2 = convertStringsToChars(import2);

                % Restitch these specific blocks with new averaged data
                app.newMin(indices(1)+2:app.g2min-1) = import;
                app.newMin(app.g2min+2:app.min-1,1) = import2;

                % Trim the useless hanging data from the cell
                app.newMin(app.min:end,:) = [];
                
                % Create new file name with no spaces
                dir = join([path,"averaged","_",app.saveName],"");
                
                % Write this matrix to new .dat file
                writecell(app.newMin, dir);

                % Let the user know where it was saved
                uialert(app.UIFigure,"The averaging is done. The file was saved here: " + dir, "Averaging Finished","Icon",'success');

            else
                % Not for averaging. If user selects only one file, correct them
                msgbox("Please select more than one file");
            end
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.TraceCountEditField.Value = app.TraceCountEditField.Value + 1;
            app.yesFiles(app.index) = app.index;

            % Store smallest index of last line to administer cutoff and properly average the channels block
            if app.Indices(3) < app.min
                app.min = app.Indices(3);
            end

            if app.Indices(2) < app.g2min
                app.g2min = app.Indices(2);
                app.newMin = app.new;
            end

            app.lag = app.lagTime(:);
            app.count = app.countRate(:);


            app.saveName = app.Filename.Value;
            app.Panel.Visible = 0;
            uiresume(app.UIFigure);
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.sz = app.sz - 1;
            app.Panel.Visible = 0;
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Corr = uiaxes(app.UIFigure);
            title(app.Corr, 'Correlation')
            xlabel(app.Corr, 'Lag time (s)')
            ylabel(app.Corr, 'g2-1')
            app.Corr.XScale = 'log';
            app.CHA = uiaxes(app.UIFigure);
            title(app.CHA, 'CH A')
            xlabel(app.CHA, 'Time (s)')
            ylabel(app.CHA, 'Count rate (kHz)')
            app.CHB = uiaxes(app.UIFigure);
            title(app.CHB, 'CH B')
            xlabel(app.CHB, 'Time (s)')
            ylabel(app.CHB, 'Count rate (kHz)')
            app.SelectfilesButton = uibutton(app.UIFigure, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select files';
            app.ScatteringangleEditFieldLabel = uilabel(app.UIFigure);
            app.ScatteringangleEditFieldLabel.Text = 'Scattering angle';
            app.ScatteringangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Value = {'Do you want to add this trace to the final average?'};
            app.YesButton = uibutton(app.Panel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.Panel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.TraceCountEditFieldLabel = uilabel(app.Panel);
            app.TraceCountEditFieldLabel.Text = 'Trace Count';
            app.TraceCountEditField = uieditfield(app.Panel, 'numeric');
            app.FilenameofdisplayeddataEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameofdisplayeddataEditFieldLabel.Text = 'Filename of displayed data:';
            app.Filename = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLSaveraging

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end